classdef BC_VARETA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BCVARETAToolboxv10UIFigure      matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        DownloadtestdataMenu            matlab.ui.container.Menu
        ImportdataMenu                  matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        CreateDataStructureMenu         matlab.ui.container.Menu
        LeadFieldComputationMenu        matlab.ui.container.Menu
        SingleSubjectMenu_LF            matlab.ui.container.Menu
        BatchProcessingMenu_LF          matlab.ui.container.Menu
        MEEGAnalysisMenu                matlab.ui.container.Menu
        SingleSubjectMenu_A             matlab.ui.container.Menu
        BatchProcessingMenu_A           matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        FigureMenu                      matlab.ui.container.Menu
        SubjectsresultMenu              matlab.ui.container.Menu
        SubjectsconnectivityMenu        matlab.ui.container.Menu
        SubjectsactivityMenu            matlab.ui.container.Menu
        RealEEGMenu                     matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        gener_OtherparamsPanel          matlab.ui.container.Panel
        RuninGPUSwitchLabel             matlab.ui.control.Label
        gener_RuninGPUSwitch            matlab.ui.control.Switch
        DisablegraphicsSwitchLabel      matlab.ui.control.Label
        gener_DisablegraphicsSwitch     matlab.ui.control.Switch
        gener_MethodsPanel_2            matlab.ui.container.Panel
        gener_SelectOutputButton        matlab.ui.control.Button
        gener_SelectInputButton         matlab.ui.control.Button
        gener_InputfolderEditField      matlab.ui.control.EditField
        InputfolderLabel                matlab.ui.control.Label
        gener_OutputfolderEditField     matlab.ui.control.EditField
        OutputfolderLabel               matlab.ui.control.Label
        gener_AnalysislevelDropDown     matlab.ui.control.DropDown
        AnalysislevelDropDownLabel      matlab.ui.control.Label
        gener_RunbytrialsSwitch         matlab.ui.control.Switch
        RunbytrialsSwitchLabel          matlab.ui.control.Label
        gener_RunbyfrequencybinSwitch   matlab.ui.control.Switch
        RunbyfrequencybinSwitchLabel    matlab.ui.control.Label
        gener_GetsystemresponseSwitch   matlab.ui.control.Switch
        GetsystemresponseSwitchLabel    matlab.ui.control.Label
        SensorTab                       matlab.ui.container.Tab
        FieldtriptemplateDropDownLabel  matlab.ui.control.Label
        sens_FieldtriptemplateDropDown  matlab.ui.control.DropDown
        sens_LayoutdescriptionPanel     matlab.ui.container.Panel
        sens_UITable                    matlab.ui.control.Table
        ActivationTab                   matlab.ui.container.Tab
        activ_MethodsPanel              matlab.ui.container.Panel
        SSSBLppLabel                    matlab.ui.control.Label
        CheckBox_sssblpp                matlab.ui.control.CheckBox
        eLORETALabel                    matlab.ui.control.Label
        CheckBox_eloreta                matlab.ui.control.CheckBox
        LCMVLabel                       matlab.ui.control.Label
        CheckBox_lcmv                   matlab.ui.control.CheckBox
        ThresholdEditFieldLabel         matlab.ui.control.Label
        sssblpp_thEditField             matlab.ui.control.NumericEditField
        ThresholdEditField_2Label       matlab.ui.control.Label
        eloreta_thEditField             matlab.ui.control.NumericEditField
        gamma1EditFieldLabel            matlab.ui.control.Label
        eloreta_gamma1EditField         matlab.ui.control.NumericEditField
        gamma2EditFieldLabel            matlab.ui.control.Label
        eloreta_gamma2EditField         matlab.ui.control.NumericEditField
        deltagammaEditFieldLabel        matlab.ui.control.Label
        eloreta_deltagammaEditField     matlab.ui.control.NumericEditField
        ThresholdEditField_3Label       matlab.ui.control.Label
        lcmv_thEditField                matlab.ui.control.NumericEditField
        gamma1EditField_2Label          matlab.ui.control.Label
        lcmv_gamma1EditField            matlab.ui.control.NumericEditField
        gamma2EditField_2Label          matlab.ui.control.Label
        lcmv_gamma2EditField            matlab.ui.control.NumericEditField
        deltagammaEditField_2Label      matlab.ui.control.Label
        lcmv_deltagammaEditField        matlab.ui.control.NumericEditField
        activ_OtherparamsPanel          matlab.ui.container.Panel
        actv_IsCurvCheckBox             matlab.ui.control.CheckBox
        activ_IsParcelCheckBox          matlab.ui.control.CheckBox
        actv_IsNeighCheckBox            matlab.ui.control.CheckBox
        aGiriLabel                      matlab.ui.control.Label
        activ_aGiriEditField            matlab.ui.control.NumericEditField
        aSulcEditFieldLabel             matlab.ui.control.Label
        activ_aSulcEditField            matlab.ui.control.NumericEditField
        bGiriEditFieldLabel             matlab.ui.control.Label
        activ_bGiriEditField            matlab.ui.control.NumericEditField
        bSulcEditFieldLabel             matlab.ui.control.Label
        activ_bSulcEditField            matlab.ui.control.NumericEditField
        regLaplacianEditFieldLabel      matlab.ui.control.Label
        activ_regLaplacianEditField     matlab.ui.control.NumericEditField
        FieldLabel                      matlab.ui.control.Label
        actv_fieldSlider                matlab.ui.control.Slider
        IsCurvLabel                     matlab.ui.control.Label
        IsParcelLabel                   matlab.ui.control.Label
        IsNeighLabel                    matlab.ui.control.Label
        ConnectivityTab                 matlab.ui.container.Tab
        conn_MethodsPanel               matlab.ui.container.Panel
        HIGGSLabel                      matlab.ui.control.Label
        CheckBox_higgs                  matlab.ui.control.CheckBox
        ThresholdEditFieldLabel_2       matlab.ui.control.Label
        higgs_thEditField               matlab.ui.control.NumericEditField
        hg_LASSOLabel                   matlab.ui.control.Label
        CheckBox_hg_lasso               matlab.ui.control.CheckBox
        ThresholdEditField_2Label_2     matlab.ui.control.Label
        hg_lasso_thEditField            matlab.ui.control.NumericEditField
        conn_OtherparamsPanel           matlab.ui.container.Panel
        conn_IsCurvCheckBox             matlab.ui.control.CheckBox
        conn_IsNeighCheckBox            matlab.ui.control.CheckBox
        aGiriEditField_2Label           matlab.ui.control.Label
        conn_aGiriEditField             matlab.ui.control.NumericEditField
        aSulcEditField_2Label           matlab.ui.control.Label
        conn_aSulcEditField             matlab.ui.control.NumericEditField
        bGiriEditField_2Label           matlab.ui.control.Label
        conn_bGiriEditField             matlab.ui.control.NumericEditField
        bSulcEditField_2Label           matlab.ui.control.Label
        conn_bSulcEditField             matlab.ui.control.NumericEditField
        regLaplacianEditField_2Label    matlab.ui.control.Label
        conn_regLaplacianEditField      matlab.ui.control.NumericEditField
        MaxiterouterEditFieldLabel      matlab.ui.control.Label
        conn_MaxiterouterEditField      matlab.ui.control.NumericEditField
        MaxiterinnerEditFieldLabel      matlab.ui.control.Label
        conn_MaxiterinnerEditField      matlab.ui.control.NumericEditField
        ntryLabel                       matlab.ui.control.Label
        conn_ntrySlider                 matlab.ui.control.Slider
        axiLabel                        matlab.ui.control.Label
        conn_axiEditField               matlab.ui.control.NumericEditField
        conn_prewarmingCheckBox         matlab.ui.control.CheckBox
        penaltySliderLabel              matlab.ui.control.Label
        conn_penaltySlider              matlab.ui.control.Slider
        rth1Label                       matlab.ui.control.Label
        conn_rth1EditField              matlab.ui.control.NumericEditField
        rth2Label                       matlab.ui.control.Label
        conn_rth2EditField              matlab.ui.control.NumericEditField
        eigregLabel                     matlab.ui.control.Label
        conn_eigregEditField            matlab.ui.control.NumericEditField
        FieldLabel_2                    matlab.ui.control.Label
        conn_fieldSlider                matlab.ui.control.Slider
        IsCurvLabel_2                   matlab.ui.control.Label
        IsNeighLabel_2                  matlab.ui.control.Label
        prewarmingLabel                 matlab.ui.control.Label
        SpectralTab                     matlab.ui.container.Tab
        spect_FreqPanel                 matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        NameLabel                       matlab.ui.control.Label
        ProcessLabel                    matlab.ui.control.Label
        StartLabel                      matlab.ui.control.Label
        EndLabel                        matlab.ui.control.Label
        delta_startEditField            matlab.ui.control.NumericEditField
        delta_endEditField              matlab.ui.control.NumericEditField
        theta_startEditField            matlab.ui.control.NumericEditField
        theta_endEditField              matlab.ui.control.NumericEditField
        alpha_startEditField            matlab.ui.control.NumericEditField
        alpha_endEditField              matlab.ui.control.NumericEditField
        beta_startEditField             matlab.ui.control.NumericEditField
        beta_endEditField               matlab.ui.control.NumericEditField
        gamma1_startEditField           matlab.ui.control.NumericEditField
        gamma1_endEditField             matlab.ui.control.NumericEditField
        gamma2_startEditField           matlab.ui.control.NumericEditField
        gamma2_endEditField             matlab.ui.control.NumericEditField
        delta_runSwitch                 matlab.ui.control.Switch
        theta_runSwitch                 matlab.ui.control.Switch
        alpha_runSwitch                 matlab.ui.control.Switch
        beta_runSwitch                  matlab.ui.control.Switch
        gamma1_runSwitch                matlab.ui.control.Switch
        gamma2_runSwitch                matlab.ui.control.Switch
        DeltaLabel                      matlab.ui.control.Label
        ThetaLabel                      matlab.ui.control.Label
        AlphaLabel                      matlab.ui.control.Label
        BetaLabel                       matlab.ui.control.Label
        Gamma1Label                     matlab.ui.control.Label
        Gamma2Label                     matlab.ui.control.Label
        spect_OtherparamsPanel          matlab.ui.container.Panel
        FreqresolutionLabel             matlab.ui.control.Label
        spect_freq_resolEditField       matlab.ui.control.NumericEditField
        SamplefreqLabel                 matlab.ui.control.Label
        spect_sample_freqEditField      matlab.ui.control.NumericEditField
        FreqmaxEditFieldLabel           matlab.ui.control.Label
        spect_FreqmaxEditField          matlab.ui.control.NumericEditField
        freq_gfiltvarEditFieldLabel     matlab.ui.control.Label
        spect_freq_gfiltvarEditField    matlab.ui.control.NumericEditField
        win_orderLabel                  matlab.ui.control.Label
        spect_win_orderSlider           matlab.ui.control.Slider
        RunButton                       matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        single_subject % Description
    end
    
    methods (Access = private)
        
        function setPromptFcn(app,jTextArea,eventData,newPrompt)
            % Prevent overlapping reentry due to prompt replacement
            persistent inProgress
            if isempty(inProgress)
                inProgress = 1;  %#ok unused
            else
                return;
            end
            
            try
                % *** Prompt modification code goes here ***
                cwText = char(jTextArea.getText);
                app.TextArea.Value = cwText;
                % force prompt-change callback to fizzle-out...
                pause(0.02);
            catch
                % Never mind - ignore errors...
            end
            
            % Enable new callbacks now that the prompt has been modified
            inProgress = [];
            
        end  % setPromptFcn
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("app");
            addpath(genpath("external\fieldtrip"));
            clc;
            processes = jsondecode(fileread(strcat('processes.json')));
            for i = 1: length(processes)
                process = processes(i);
                if(process.active)
                    addpath(process.root_folder);
                end
            end
            ft_template_dir = fullfile("external/fieldtrip/template/layout");
            layouts = dir(ft_template_dir);
            layouts(ismember( {layouts.name}, {'.', '..'})) = [];  %remove . and ..
            app.sens_FieldtriptemplateDropDown.Items = {layouts.name};
            app.sens_FieldtriptemplateDropDown.Items{end+1} = '-Select a layout-';
            app.sens_FieldtriptemplateDropDown.Value = '-Select a layout-';
            %             try
            %                 jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
            %                 jCmdWin = jDesktop.getClient('Command Window');
            %                 jTextArea = jCmdWin.getComponent(0).getViewport.getView;
            %                 set(jTextArea,'CaretUpdateCallback',@app.setPromptFcn)
            %             catch
            %                 warndlg('fatal error');
            %             end
        end

        % Menu selected function: CreateDataStructureMenu
        function CreateDataStructureMenuSelected(app, event)
            folder = uigetdir('tittle','Select the Source Folder');
            if(folder==0)
                return;
            end
            create_data_structure(folder);
            msgbox('Completed operation!!!','Info');
        end

        % Callback function
        function ButtonPushed(app, event)
            jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
            jCmdWin = jDesktop.getClient('Command Window');
            jTextArea = jCmdWin.getComponent(0).getViewport.getView;
            cwText = char(jTextArea.getText);
            
            set(jTextArea,'CaretUpdateCallback',@myUpdateFcn)
            
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Menu selected function: DownloadtestdataMenu
        function DownloadtestdataMenuSelected(app, event)
            folder = uigetdir('tittle','Select the Source Folder');
            if(folder==0)
                return;
            end
            
            f = dialog('Position',[300 300 250 80]);
            
            iconsClassName = 'com.mathworks.widgets.BusyAffordance$AffordanceSize';
            iconsSizeEnums = javaMethod('values',iconsClassName);
            SIZE_32x32 = iconsSizeEnums(2);  % (1) = 16x16,  (2) = 32x32
            jObj = com.mathworks.widgets.BusyAffordance(SIZE_32x32, 'Downloading test data...');  % icon, label
            
            jObj.setPaintsWhenStopped(true);  % default = false
            jObj.useWhiteDots(false);         % default = false (true is good for dark backgrounds)
            javacomponent(jObj.getComponent, [50,10,150,80], f);
            jObj.start;
            pause(1);
            
            app_properties= jsondecode(fileread(strcat('app_properties.json')));
            url = app_properties.generals.test_data_url;
            filename = strcat(folder,filesep,'BC_VARETA_test_data.zip');
            matlab.net.http.HTTPOptions.VerifyServerName = false;
            options = weboptions('Timeout',Inf,'RequestMethod','get');
            
            try
                disp('Downloding test data....');
                outfilename = websave(filename,url,options);
            catch
                delete(f);
                errordlg('Download error!!!','Error');
                return;
            end
            try
                disp('Unpacking test data....');
                exampleFiles = unzip(filename,folder);
            catch
                delete(f);
                errordlg('Unpackage error!!!','Error');
                return;
            end
            jObj.stop;
            jObj.setBusyText('All done!');
            disp('All done....');
            pause(2);
            delete(f);
            msgbox('Download complete','Info');
        end

        % Menu selected function: FigureMenu
        function FigureMenuSelected(app, event)
            
            [file,path] = uigetfile('*.fig');
            if isequal(file,0)
                disp('User selected Cancel');
                return;
            end
            openfig(strcat(path,filesep,file));
        end

        % Menu selected function: SubjectsresultMenu
        function SubjectsresultMenuSelected(app, event)
            folder = uigetdir('tittle','Select the Source Folder');
            if(folder==0)
                return;
            end
            files = dir(folder);
            ext='.fig';
            for j=1:size(files,1)
                file_name = files(j).name;
                file_path = strcat(folder,filesep, file_name);
                [~,name,ex]=fileparts(file_name);
                %% ----------Searching de data files ------------------------------------
                if(~isfolder(file_path) & strcmpi(strtrim(ex),ext) )
                    openfig(strcat(file_path));
                end
            end
        end

        % Menu selected function: RealEEGMenu
        function RealEEGMenuSelected(app, event)
            %             [file,path] = uigetfile('*.mat');
            %             if isequal(file,0)
            %                 disp('User selected Cancel');
            %                 return;
            %             end
            %             real_EEG=load(strcat(path,filesep,file));
            %
            %             figure_EEG = figure('Color','k','Name',file,'NumberTitle','off','units','normalized','outerposition',[0 0 1 1]);
            %             subplot(1,3,1); plot(real_EEG.Sjj);
            %             %             ylabel('generators')
            %             %     xlabel('generators')
            %             %     title('simulated partial correlations')
            %             subplot(1,3,2); plot(real_EEG.Thetajj);
            %             %             ylabel('generators')
            %             %     xlabel('generators')
            %             %     title('simulated partial correlations')
            %             subplot(1,3,3); plot(real_EEG.indms);
            
        end

        % Menu selected function: SingleSubjectMenu_A
        function SingleSubjectMenu_ASelected(app, event)
            addpath('functions');
            bcv_properties = jsondecode(fileread(fullfile('bcv_properties','bcv_properties.json')));
            bcv_properties.run_single_subject.value = true;
            saveJSON(bcv_properties,fullfile('bcv_properties','bcv_properties.json'));
            BC_VARETA_bash;
            msgbox('Completed operation!!!','Info');
        end

        % Menu selected function: BatchProcessingMenu_A
        function BatchProcessingMenu_ASelected(app, event)
            bcv_properties = jsondecode(fileread(fullfile('bcv_properties','bcv_properties.json')));
            bcv_properties.run_single_subject.value = false;
            saveJSON(bcv_properties,fullfile('bcv_properties','bcv_properties.json'));
            BC_VARETA_bash;
            msgbox('Completed operation!!!','Info');
        end

        % Menu selected function: SingleSubjectMenu_LF
        function SingleSubjectMenu_LFSelected(app, event)
            %             addpath('bst_lf_ppl');
            %             bs_lf_ppl;
            %             msgbox('Completed operation!!!','Info');
        end

        % Menu selected function: BatchProcessingMenu_LF
        function BatchProcessingMenu_LFSelected(app, event)
            %             addpath('bst_lf_ppl');
            %             bs_lf_ppl;
            %             msgbox('Completed operation!!!','Info');
        end

        % Menu selected function: SubjectsconnectivityMenu
        function SubjectsconnectivityMenuSelected(app, event)
            folder = uigetdir('tittle','Select the Subject''s result folder');
            if(folder==0)
                return;
            end
            files = dir(folder);
            ext='.fig';
            for j=1:size(files,1)
                file_name = files(j).name;
                file_path = strcat(folder,filesep, file_name);
                [~,name,ex]=fileparts(file_name);
                %% ----------Searching de data files ------------------------------------
                if(~isfolder(file_path) & strcmpi(strtrim(ex),ext) & contains(file_name,'roi_conn'))
                    openfig(strcat(file_path));
                end
            end
        end

        % Menu selected function: SubjectsactivityMenu
        function SubjectsactivityMenuSelected(app, event)
            folder = uigetdir('tittle','Select the Subject''s result folder');
            if(folder==0)
                return;
            end
            files = dir(folder);
            ext='.fig';
            for j=1:size(files,1)
                file_name = files(j).name;
                file_path = strcat(folder,filesep, file_name);
                [~,name,ex]=fileparts(file_name);
                %% ----------Searching de data files ------------------------------------
                if(~isfolder(file_path) & strcmpi(strtrim(ex),ext) & contains(file_name,'activity') )
                    openfig(strcat(file_path));
                end
            end
        end

        % Menu selected function: ImportdataMenu
        function ImportdataMenuSelected(app, event)
            import_data_structure;
        end

        % Button pushed function: gener_SelectInputButton
        function gener_SelectInputButtonPushed(app, event)
            try
                folder = uigetdir("Select the input data folder");
                subjects = dir(fullfile(folder,'**','subject.mat'));
                if(~isempty(subjects))
                    app.gener_InputfolderEditField.Value = folder;
                else
                    msgbox({'The selected folder do not contains any BC-VARETA structure.',' Please select a correct folder.'},'Info');
                end
            catch
            end
        end

        % Button pushed function: gener_SelectOutputButton
        function gener_SelectOutputButtonPushed(app, event)
            try
                folder = uigetdir("Select the Output folder");
                [status,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.gener_InputfolderEditField.Value = folder;
                else
                    msgbox({'The current user do not have write permissions on the selected forder.',...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
            end
        end

        % Value changed function: sens_FieldtriptemplateDropDown
        function sens_FieldtriptemplateDropDownValueChanged(app, event)
            layout = app.sens_FieldtriptemplateDropDown.Value;
            if ft_filetype(layout, 'matlab')
                tmp = load(layout, 'lay*');
                if isfield(tmp, 'layout')
                    layout = tmp.layout;
                elseif isfield(tmp, 'lay')
                    layout = tmp.lay;
                else
                    ft_error('mat file does not contain a layout');
                end
            else
                layout = readlay(layout);
            end
            order_layout.No         = [1:length(layout.label)]';
            order_layout.Labels     = layout.label;
            order_layout.Position   = layout.pos;
            order_layout.Width      = layout.width;
            order_layout.Height     = layout.height;
            T                       = struct2table(order_layout);
            set(app.sens_UITable, 'Data', T);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BCVARETAToolboxv10UIFigure = uifigure('Visible', 'off');
            app.BCVARETAToolboxv10UIFigure.Name = 'BC-VARETA Toolbox v1.0';
            app.FileMenu = uimenu(app.BCVARETAToolboxv10UIFigure);
            app.FileMenu.Text = 'File';
            app.DownloadtestdataMenu = uimenu(app.FileMenu);
            app.DownloadtestdataMenu.MenuSelectedFcn = createCallbackFcn(app, @DownloadtestdataMenuSelected, true);
            app.DownloadtestdataMenu.Text = 'Download test data';
            app.ImportdataMenu = uimenu(app.FileMenu);
            app.ImportdataMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportdataMenuSelected, true);
            app.ImportdataMenu.Text = 'Import data';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ToolsMenu = uimenu(app.BCVARETAToolboxv10UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.CreateDataStructureMenu = uimenu(app.ToolsMenu);
            app.CreateDataStructureMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateDataStructureMenuSelected, true);
            app.CreateDataStructureMenu.Text = 'Create Data Structure';
            app.LeadFieldComputationMenu = uimenu(app.ToolsMenu);
            app.LeadFieldComputationMenu.Text = 'Lead Field Computation';
            app.SingleSubjectMenu_LF = uimenu(app.LeadFieldComputationMenu);
            app.SingleSubjectMenu_LF.MenuSelectedFcn = createCallbackFcn(app, @SingleSubjectMenu_LFSelected, true);
            app.SingleSubjectMenu_LF.Text = 'Single Subject';
            app.BatchProcessingMenu_LF = uimenu(app.LeadFieldComputationMenu);
            app.BatchProcessingMenu_LF.MenuSelectedFcn = createCallbackFcn(app, @BatchProcessingMenu_LFSelected, true);
            app.BatchProcessingMenu_LF.Text = 'Batch Processing';
            app.MEEGAnalysisMenu = uimenu(app.ToolsMenu);
            app.MEEGAnalysisMenu.Text = 'MEEG Analysis';
            app.SingleSubjectMenu_A = uimenu(app.MEEGAnalysisMenu);
            app.SingleSubjectMenu_A.MenuSelectedFcn = createCallbackFcn(app, @SingleSubjectMenu_ASelected, true);
            app.SingleSubjectMenu_A.Text = 'Single Subject';
            app.BatchProcessingMenu_A = uimenu(app.MEEGAnalysisMenu);
            app.BatchProcessingMenu_A.MenuSelectedFcn = createCallbackFcn(app, @BatchProcessingMenu_ASelected, true);
            app.BatchProcessingMenu_A.Text = 'Batch Processing';
            app.ViewMenu = uimenu(app.BCVARETAToolboxv10UIFigure);
            app.ViewMenu.Text = 'View';
            app.FigureMenu = uimenu(app.ViewMenu);
            app.FigureMenu.MenuSelectedFcn = createCallbackFcn(app, @FigureMenuSelected, true);
            app.FigureMenu.Text = 'Figure';
            app.SubjectsresultMenu = uimenu(app.ViewMenu);
            app.SubjectsresultMenu.MenuSelectedFcn = createCallbackFcn(app, @SubjectsresultMenuSelected, true);
            app.SubjectsresultMenu.Text = 'Subject''s result';
            app.SubjectsconnectivityMenu = uimenu(app.ViewMenu);
            app.SubjectsconnectivityMenu.MenuSelectedFcn = createCallbackFcn(app, @SubjectsconnectivityMenuSelected, true);
            app.SubjectsconnectivityMenu.Text = 'Subject''s connectivity';
            app.SubjectsactivityMenu = uimenu(app.ViewMenu);
            app.SubjectsactivityMenu.MenuSelectedFcn = createCallbackFcn(app, @SubjectsactivityMenuSelected, true);
            app.SubjectsactivityMenu.Text = 'Subject''s activity';
            app.RealEEGMenu = uimenu(app.ViewMenu);
            app.RealEEGMenu.MenuSelectedFcn = createCallbackFcn(app, @RealEEGMenuSelected, true);
            app.RealEEGMenu.Text = 'Real EEG ';
            app.HelpMenu = uimenu(app.BCVARETAToolboxv10UIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.BCVARETAToolboxv10UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.gener_OtherparamsPanel = uipanel(app.GeneralTab);
            app.gener_OtherparamsPanel.Title = 'Other params';
            app.RuninGPUSwitchLabel = uilabel(app.gener_OtherparamsPanel);
            app.RuninGPUSwitchLabel.Text = 'Run in GPU:';
            app.gener_RuninGPUSwitch = uiswitch(app.gener_OtherparamsPanel, 'slider');
            app.gener_RuninGPUSwitch.Items = {'False', 'True'};
            app.gener_RuninGPUSwitch.Value = 'False';
            app.DisablegraphicsSwitchLabel = uilabel(app.gener_OtherparamsPanel);
            app.DisablegraphicsSwitchLabel.Text = 'Disable graphics:';
            app.gener_DisablegraphicsSwitch = uiswitch(app.gener_OtherparamsPanel, 'slider');
            app.gener_DisablegraphicsSwitch.Items = {'False', 'True'};
            app.gener_DisablegraphicsSwitch.Value = 'False';
            app.gener_MethodsPanel_2 = uipanel(app.GeneralTab);
            app.gener_MethodsPanel_2.Title = 'Principal params';
            app.gener_SelectOutputButton = uibutton(app.gener_MethodsPanel_2, 'push');
            app.gener_SelectOutputButton.ButtonPushedFcn = createCallbackFcn(app, @gener_SelectOutputButtonPushed, true);
            app.gener_SelectOutputButton.Text = 'Select';
            app.gener_SelectInputButton = uibutton(app.gener_MethodsPanel_2, 'push');
            app.gener_SelectInputButton.ButtonPushedFcn = createCallbackFcn(app, @gener_SelectInputButtonPushed, true);
            app.gener_SelectInputButton.Text = 'Select';
            app.gener_InputfolderEditField = uieditfield(app.gener_MethodsPanel_2, 'text');
            app.InputfolderLabel = uilabel(app.gener_MethodsPanel_2);
            app.InputfolderLabel.Text = 'Input folder:';
            app.gener_OutputfolderEditField = uieditfield(app.gener_MethodsPanel_2, 'text');
            app.OutputfolderLabel = uilabel(app.gener_MethodsPanel_2);
            app.OutputfolderLabel.Text = 'Output folder:';
            app.gener_AnalysislevelDropDown = uidropdown(app.gener_MethodsPanel_2);
            app.gener_AnalysislevelDropDown.Items = {'All analysis', 'Sensor', 'Activation', 'Connectivity', 'Sensor and Activation', 'Activation and Connectivity'};
            app.gener_AnalysislevelDropDown.Value = 'All analysis';
            app.AnalysislevelDropDownLabel = uilabel(app.gener_MethodsPanel_2);
            app.AnalysislevelDropDownLabel.Text = 'Analysis level:';
            app.gener_RunbytrialsSwitch = uiswitch(app.gener_MethodsPanel_2, 'slider');
            app.gener_RunbytrialsSwitch.Items = {'False', 'True'};
            app.gener_RunbytrialsSwitch.Value = 'False';
            app.RunbytrialsSwitchLabel = uilabel(app.gener_MethodsPanel_2);
            app.RunbytrialsSwitchLabel.Text = 'Run by trials:';
            app.gener_RunbyfrequencybinSwitch = uiswitch(app.gener_MethodsPanel_2, 'slider');
            app.gener_RunbyfrequencybinSwitch.Items = {'False', 'True'};
            app.gener_RunbyfrequencybinSwitch.Value = 'False';
            app.RunbyfrequencybinSwitchLabel = uilabel(app.gener_MethodsPanel_2);
            app.RunbyfrequencybinSwitchLabel.Text = 'Run by frequency bin:';
            app.gener_GetsystemresponseSwitch = uiswitch(app.gener_MethodsPanel_2, 'slider');
            app.gener_GetsystemresponseSwitch.Items = {'False', 'True'};
            app.gener_GetsystemresponseSwitch.Value = 'True';
            app.GetsystemresponseSwitchLabel = uilabel(app.gener_MethodsPanel_2);
            app.GetsystemresponseSwitchLabel.Text = 'Get system response:';
            app.SensorTab = uitab(app.TabGroup);
            app.SensorTab.Title = 'Sensor';
            app.FieldtriptemplateDropDownLabel = uilabel(app.SensorTab);
            app.FieldtriptemplateDropDownLabel.Text = 'Fieldtrip template:';
            app.sens_FieldtriptemplateDropDown = uidropdown(app.SensorTab);
            app.sens_FieldtriptemplateDropDown.ValueChangedFcn = createCallbackFcn(app, @sens_FieldtriptemplateDropDownValueChanged, true);
            app.sens_LayoutdescriptionPanel = uipanel(app.SensorTab);
            app.sens_LayoutdescriptionPanel.Title = 'Layout description';
            app.sens_UITable = uitable(app.sens_LayoutdescriptionPanel);
            app.sens_UITable.ColumnName = {'No.'; 'Labels'; 'Position'; 'Width'; 'Height'};
            app.sens_UITable.RowName = {};
            app.ActivationTab = uitab(app.TabGroup);
            app.ActivationTab.Title = 'Activation';
            app.activ_MethodsPanel = uipanel(app.ActivationTab);
            app.activ_MethodsPanel.Title = 'Methods';
            app.SSSBLppLabel = uilabel(app.activ_MethodsPanel);
            app.SSSBLppLabel.Text = 'SSSBLpp:';
            app.CheckBox_sssblpp = uicheckbox(app.activ_MethodsPanel);
            app.CheckBox_sssblpp.Text = '';
            app.eLORETALabel = uilabel(app.activ_MethodsPanel);
            app.eLORETALabel.Text = 'eLORETA:';
            app.CheckBox_eloreta = uicheckbox(app.activ_MethodsPanel);
            app.CheckBox_eloreta.Text = '';
            app.LCMVLabel = uilabel(app.activ_MethodsPanel);
            app.LCMVLabel.Text = 'LCMV:';
            app.CheckBox_lcmv = uicheckbox(app.activ_MethodsPanel);
            app.CheckBox_lcmv.Text = '';
            app.ThresholdEditFieldLabel = uilabel(app.activ_MethodsPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold:';
            app.sssblpp_thEditField = uieditfield(app.activ_MethodsPanel, 'numeric');
            app.sssblpp_thEditField.Limits = [0 1.7321];
            app.sssblpp_thEditField.Value = 1;
            app.ThresholdEditField_2Label = uilabel(app.activ_MethodsPanel);
            app.ThresholdEditField_2Label.Text = 'Threshold:';
            app.eloreta_thEditField = uieditfield(app.activ_MethodsPanel, 'numeric');
            app.eloreta_thEditField.Limits = [0 1.7321];
            app.eloreta_thEditField.Value = 1;
            app.gamma1EditFieldLabel = uilabel(app.activ_MethodsPanel);
            app.gamma1EditFieldLabel.Text = 'gamma1:';
            app.eloreta_gamma1EditField = uieditfield(app.activ_MethodsPanel, 'numeric');
            app.eloreta_gamma1EditField.Limits = [0 2];
            app.gamma2EditFieldLabel = uilabel(app.activ_MethodsPanel);
            app.gamma2EditFieldLabel.Text = 'gamma2:';
            app.eloreta_gamma2EditField = uieditfield(app.activ_MethodsPanel, 'numeric');
            app.eloreta_gamma2EditField.Limits = [0 2];
            app.eloreta_gamma2EditField.Value = 2;
            app.deltagammaEditFieldLabel = uilabel(app.activ_MethodsPanel);
            app.deltagammaEditFieldLabel.Text = 'delta gamma:';
            app.eloreta_deltagammaEditField = uieditfield(app.activ_MethodsPanel, 'numeric');
            app.eloreta_deltagammaEditField.Limits = [0.1 1];
            app.eloreta_deltagammaEditField.Value = 0.1;
            app.ThresholdEditField_3Label = uilabel(app.activ_MethodsPanel);
            app.ThresholdEditField_3Label.Text = 'Threshold:';
            app.lcmv_thEditField = uieditfield(app.activ_MethodsPanel, 'numeric');
            app.lcmv_thEditField.Limits = [0 1.7321];
            app.lcmv_thEditField.Value = 1;
            app.gamma1EditField_2Label = uilabel(app.activ_MethodsPanel);
            app.gamma1EditField_2Label.Text = 'gamma1:';
            app.lcmv_gamma1EditField = uieditfield(app.activ_MethodsPanel, 'numeric');
            app.lcmv_gamma1EditField.Limits = [0 2];
            app.gamma2EditField_2Label = uilabel(app.activ_MethodsPanel);
            app.gamma2EditField_2Label.Text = 'gamma2:';
            app.lcmv_gamma2EditField = uieditfield(app.activ_MethodsPanel, 'numeric');
            app.lcmv_gamma2EditField.Limits = [0 2];
            app.lcmv_gamma2EditField.Value = 2;
            app.deltagammaEditField_2Label = uilabel(app.activ_MethodsPanel);
            app.deltagammaEditField_2Label.Text = 'delta gamma:';
            app.lcmv_deltagammaEditField = uieditfield(app.activ_MethodsPanel, 'numeric');
            app.lcmv_deltagammaEditField.Limits = [0.1 1];
            app.lcmv_deltagammaEditField.Value = 0.1;
            app.activ_OtherparamsPanel = uipanel(app.ActivationTab);
            app.activ_OtherparamsPanel.Title = 'Other params';
            app.actv_IsCurvCheckBox = uicheckbox(app.activ_OtherparamsPanel);
            app.actv_IsCurvCheckBox.Text = '';
            app.activ_IsParcelCheckBox = uicheckbox(app.activ_OtherparamsPanel);
            app.activ_IsParcelCheckBox.Text = '';
            app.actv_IsNeighCheckBox = uicheckbox(app.activ_OtherparamsPanel);
            app.actv_IsNeighCheckBox.Text = '';
            app.aGiriLabel = uilabel(app.activ_OtherparamsPanel);
            app.aGiriLabel.Text = 'aGiri:';
            app.activ_aGiriEditField = uieditfield(app.activ_OtherparamsPanel, 'numeric');
            app.activ_aGiriEditField.Limits = [1 5];
            app.activ_aGiriEditField.Value = 5;
            app.aSulcEditFieldLabel = uilabel(app.activ_OtherparamsPanel);
            app.aSulcEditFieldLabel.Text = 'aSulc:';
            app.activ_aSulcEditField = uieditfield(app.activ_OtherparamsPanel, 'numeric');
            app.activ_aSulcEditField.Limits = [1 5];
            app.activ_aSulcEditField.Value = 5;
            app.bGiriEditFieldLabel = uilabel(app.activ_OtherparamsPanel);
            app.bGiriEditFieldLabel.Text = 'bGiri:';
            app.activ_bGiriEditField = uieditfield(app.activ_OtherparamsPanel, 'numeric');
            app.activ_bGiriEditField.Limits = [0 5];
            app.activ_bGiriEditField.Value = 3;
            app.bSulcEditFieldLabel = uilabel(app.activ_OtherparamsPanel);
            app.bSulcEditFieldLabel.Text = 'bSulc:';
            app.activ_bSulcEditField = uieditfield(app.activ_OtherparamsPanel, 'numeric');
            app.activ_bSulcEditField.Limits = [1 5];
            app.activ_bSulcEditField.Value = 3;
            app.regLaplacianEditFieldLabel = uilabel(app.activ_OtherparamsPanel);
            app.regLaplacianEditFieldLabel.Text = 'regLaplacian:';
            app.activ_regLaplacianEditField = uieditfield(app.activ_OtherparamsPanel, 'numeric');
            app.activ_regLaplacianEditField.Limits = [0.1 1];
            app.activ_regLaplacianEditField.Value = 0.1;
            app.FieldLabel = uilabel(app.activ_OtherparamsPanel);
            app.FieldLabel.Text = 'Field:';
            app.actv_fieldSlider = uislider(app.activ_OtherparamsPanel);
            app.actv_fieldSlider.Limits = [1 3];
            app.actv_fieldSlider.MajorTicks = [1 2 3];
            app.actv_fieldSlider.MinorTicks = [1 2 3];
            app.actv_fieldSlider.Value = 2;
            app.IsCurvLabel = uilabel(app.activ_OtherparamsPanel);
            app.IsCurvLabel.Text = 'IsCurv:';
            app.IsParcelLabel = uilabel(app.activ_OtherparamsPanel);
            app.IsParcelLabel.Text = 'IsParcel:';
            app.IsNeighLabel = uilabel(app.activ_OtherparamsPanel);
            app.IsNeighLabel.Text = 'IsNeigh:';
            app.ConnectivityTab = uitab(app.TabGroup);
            app.ConnectivityTab.Title = 'Connectivity';
            app.conn_MethodsPanel = uipanel(app.ConnectivityTab);
            app.conn_MethodsPanel.Title = 'Methods';
            app.HIGGSLabel = uilabel(app.conn_MethodsPanel);
            app.HIGGSLabel.Text = 'HIGGS:';
            app.CheckBox_higgs = uicheckbox(app.conn_MethodsPanel);
            app.CheckBox_higgs.Text = '';
            app.ThresholdEditFieldLabel_2 = uilabel(app.conn_MethodsPanel);
            app.ThresholdEditFieldLabel_2.Text = 'Threshold:';
            app.higgs_thEditField = uieditfield(app.conn_MethodsPanel, 'numeric');
            app.higgs_thEditField.Limits = [0 1.7321];
            app.higgs_thEditField.Value = 1;
            app.hg_LASSOLabel = uilabel(app.conn_MethodsPanel);
            app.hg_LASSOLabel.Text = 'hg_LASSO:';
            app.CheckBox_hg_lasso = uicheckbox(app.conn_MethodsPanel);
            app.CheckBox_hg_lasso.Text = '';
            app.ThresholdEditField_2Label_2 = uilabel(app.conn_MethodsPanel);
            app.ThresholdEditField_2Label_2.Text = 'Threshold:';
            app.hg_lasso_thEditField = uieditfield(app.conn_MethodsPanel, 'numeric');
            app.hg_lasso_thEditField.Limits = [0 1.7321];
            app.hg_lasso_thEditField.Value = 1;
            app.conn_OtherparamsPanel = uipanel(app.ConnectivityTab);
            app.conn_OtherparamsPanel.Title = 'Other params';
            app.conn_IsCurvCheckBox = uicheckbox(app.conn_OtherparamsPanel);
            app.conn_IsCurvCheckBox.Text = '';
            app.conn_IsNeighCheckBox = uicheckbox(app.conn_OtherparamsPanel);
            app.conn_IsNeighCheckBox.Text = '';
            app.aGiriEditField_2Label = uilabel(app.conn_OtherparamsPanel);
            app.aGiriEditField_2Label.Text = 'aGiri:';
            app.conn_aGiriEditField = uieditfield(app.conn_OtherparamsPanel, 'numeric');
            app.conn_aGiriEditField.Limits = [1 5];
            app.conn_aGiriEditField.Value = 5;
            app.aSulcEditField_2Label = uilabel(app.conn_OtherparamsPanel);
            app.aSulcEditField_2Label.Text = 'aSulc:';
            app.conn_aSulcEditField = uieditfield(app.conn_OtherparamsPanel, 'numeric');
            app.conn_aSulcEditField.Limits = [1 5];
            app.conn_aSulcEditField.Value = 5;
            app.bGiriEditField_2Label = uilabel(app.conn_OtherparamsPanel);
            app.bGiriEditField_2Label.Text = 'bGiri:';
            app.conn_bGiriEditField = uieditfield(app.conn_OtherparamsPanel, 'numeric');
            app.conn_bGiriEditField.Limits = [0 5];
            app.conn_bGiriEditField.Value = 3;
            app.bSulcEditField_2Label = uilabel(app.conn_OtherparamsPanel);
            app.bSulcEditField_2Label.Text = 'bSulc:';
            app.conn_bSulcEditField = uieditfield(app.conn_OtherparamsPanel, 'numeric');
            app.conn_bSulcEditField.Limits = [1 5];
            app.conn_bSulcEditField.Value = 3;
            app.regLaplacianEditField_2Label = uilabel(app.conn_OtherparamsPanel);
            app.regLaplacianEditField_2Label.Text = 'regLaplacian:';
            app.conn_regLaplacianEditField = uieditfield(app.conn_OtherparamsPanel, 'numeric');
            app.conn_regLaplacianEditField.Limits = [0.1 1];
            app.conn_regLaplacianEditField.Value = 0.1;
            app.MaxiterouterEditFieldLabel = uilabel(app.conn_OtherparamsPanel);
            app.MaxiterouterEditFieldLabel.Text = 'Maxiter outer:';
            app.conn_MaxiterouterEditField = uieditfield(app.conn_OtherparamsPanel, 'numeric');
            app.conn_MaxiterouterEditField.Limits = [20 80];
            app.conn_MaxiterouterEditField.Value = 60;
            app.MaxiterinnerEditFieldLabel = uilabel(app.conn_OtherparamsPanel);
            app.MaxiterinnerEditFieldLabel.Text = 'Maxiter inner:';
            app.conn_MaxiterinnerEditField = uieditfield(app.conn_OtherparamsPanel, 'numeric');
            app.conn_MaxiterinnerEditField.Limits = [20 40];
            app.conn_MaxiterinnerEditField.Value = 30;
            app.ntryLabel = uilabel(app.conn_OtherparamsPanel);
            app.ntryLabel.Text = 'ntry:';
            app.conn_ntrySlider = uislider(app.conn_OtherparamsPanel);
            app.conn_ntrySlider.Limits = [0 5];
            app.conn_ntrySlider.MajorTicks = [0 1 2 3 4 5];
            app.conn_ntrySlider.MinorTicks = [0 1 2 3 4 5];
            app.axiLabel = uilabel(app.conn_OtherparamsPanel);
            app.axiLabel.Text = 'axi:';
            app.conn_axiEditField = uieditfield(app.conn_OtherparamsPanel, 'numeric');
            app.conn_axiEditField.Limits = [0.1 1];
            app.conn_axiEditField.Value = 0.1;
            app.conn_prewarmingCheckBox = uicheckbox(app.conn_OtherparamsPanel);
            app.conn_prewarmingCheckBox.Text = '';
            app.penaltySliderLabel = uilabel(app.conn_OtherparamsPanel);
            app.penaltySliderLabel.Text = 'penalty:';
            app.conn_penaltySlider = uislider(app.conn_OtherparamsPanel);
            app.conn_penaltySlider.Limits = [0 2];
            app.conn_penaltySlider.MajorTicks = [0 1 2];
            app.conn_penaltySlider.MinorTicks = [0 1 2];
            app.rth1Label = uilabel(app.conn_OtherparamsPanel);
            app.rth1Label.Text = 'rth1:';
            app.conn_rth1EditField = uieditfield(app.conn_OtherparamsPanel, 'numeric');
            app.conn_rth1EditField.Limits = [0.1 1];
            app.conn_rth1EditField.Value = 0.7;
            app.rth2Label = uilabel(app.conn_OtherparamsPanel);
            app.rth2Label.Text = 'rth2:';
            app.conn_rth2EditField = uieditfield(app.conn_OtherparamsPanel, 'numeric');
            app.conn_rth2EditField.Limits = [0.1 5];
            app.conn_rth2EditField.Value = 3.16;
            app.eigregLabel = uilabel(app.conn_OtherparamsPanel);
            app.eigregLabel.Text = 'eigreg:';
            app.conn_eigregEditField = uieditfield(app.conn_OtherparamsPanel, 'numeric');
            app.conn_eigregEditField.Limits = [0.0001 0.1];
            app.conn_eigregEditField.Value = 0.0001;
            app.FieldLabel_2 = uilabel(app.conn_OtherparamsPanel);
            app.FieldLabel_2.Text = 'Field:';
            app.conn_fieldSlider = uislider(app.conn_OtherparamsPanel);
            app.conn_fieldSlider.Limits = [1 3];
            app.conn_fieldSlider.MajorTicks = [1 2 3];
            app.conn_fieldSlider.MinorTicks = [1 2 3];
            app.conn_fieldSlider.Value = 2;
            app.IsCurvLabel_2 = uilabel(app.conn_OtherparamsPanel);
            app.IsCurvLabel_2.Text = 'IsCurv:';
            app.IsNeighLabel_2 = uilabel(app.conn_OtherparamsPanel);
            app.IsNeighLabel_2.Text = 'IsNeigh:';
            app.prewarmingLabel = uilabel(app.conn_OtherparamsPanel);
            app.prewarmingLabel.Text = 'prewarming:';
            app.SpectralTab = uitab(app.TabGroup);
            app.SpectralTab.Title = 'Spectral';
            app.spect_FreqPanel = uipanel(app.SpectralTab);
            app.spect_FreqPanel.Title = 'Frequency bands';
            app.GridLayout = uigridlayout(app.spect_FreqPanel);
            app.NameLabel = uilabel(app.GridLayout);
            app.NameLabel.Text = 'Name';
            app.ProcessLabel = uilabel(app.GridLayout);
            app.ProcessLabel.Text = 'Process';
            app.StartLabel = uilabel(app.GridLayout);
            app.StartLabel.Text = 'Start';
            app.EndLabel = uilabel(app.GridLayout);
            app.EndLabel.Text = 'End';
            app.delta_startEditField = uieditfield(app.GridLayout, 'numeric');
            app.delta_startEditField.Limits = [0 4];
            app.delta_endEditField = uieditfield(app.GridLayout, 'numeric');
            app.delta_endEditField.Limits = [0 4];
            app.delta_endEditField.Value = 4;
            app.theta_startEditField = uieditfield(app.GridLayout, 'numeric');
            app.theta_startEditField.Limits = [4 7];
            app.theta_startEditField.Value = 4;
            app.theta_endEditField = uieditfield(app.GridLayout, 'numeric');
            app.theta_endEditField.Limits = [4 7];
            app.theta_endEditField.Value = 7;
            app.alpha_startEditField = uieditfield(app.GridLayout, 'numeric');
            app.alpha_startEditField.Limits = [7 14];
            app.alpha_startEditField.Value = 7;
            app.alpha_endEditField = uieditfield(app.GridLayout, 'numeric');
            app.alpha_endEditField.Limits = [7 14];
            app.alpha_endEditField.Value = 14;
            app.beta_startEditField = uieditfield(app.GridLayout, 'numeric');
            app.beta_startEditField.Limits = [14 31];
            app.beta_startEditField.Value = 14;
            app.beta_endEditField = uieditfield(app.GridLayout, 'numeric');
            app.beta_endEditField.Limits = [14 31];
            app.beta_endEditField.Value = 31;
            app.gamma1_startEditField = uieditfield(app.GridLayout, 'numeric');
            app.gamma1_startEditField.Limits = [31 60];
            app.gamma1_startEditField.Value = 31;
            app.gamma1_endEditField = uieditfield(app.GridLayout, 'numeric');
            app.gamma1_endEditField.Limits = [31 60];
            app.gamma1_endEditField.Value = 60;
            app.gamma2_startEditField = uieditfield(app.GridLayout, 'numeric');
            app.gamma2_startEditField.Limits = [60 90];
            app.gamma2_startEditField.Value = 60;
            app.gamma2_endEditField = uieditfield(app.GridLayout, 'numeric');
            app.gamma2_endEditField.Limits = [60 90];
            app.gamma2_endEditField.Value = 90;
            app.delta_runSwitch = uiswitch(app.GridLayout, 'slider');
            app.delta_runSwitch.Items = {'No', 'Yes'};
            app.delta_runSwitch.Value = 'Yes';
            app.theta_runSwitch = uiswitch(app.GridLayout, 'slider');
            app.theta_runSwitch.Items = {'No', 'Yes'};
            app.theta_runSwitch.Value = 'Yes';
            app.alpha_runSwitch = uiswitch(app.GridLayout, 'slider');
            app.alpha_runSwitch.Items = {'No', 'Yes'};
            app.alpha_runSwitch.Value = 'Yes';
            app.beta_runSwitch = uiswitch(app.GridLayout, 'slider');
            app.beta_runSwitch.Items = {'No', 'Yes'};
            app.beta_runSwitch.Value = 'Yes';
            app.gamma1_runSwitch = uiswitch(app.GridLayout, 'slider');
            app.gamma1_runSwitch.Items = {'No', 'Yes'};
            app.gamma1_runSwitch.Value = 'Yes';
            app.gamma2_runSwitch = uiswitch(app.GridLayout, 'slider');
            app.gamma2_runSwitch.Items = {'No', 'Yes'};
            app.gamma2_runSwitch.Value = 'Yes';
            app.DeltaLabel = uilabel(app.GridLayout);
            app.DeltaLabel.Text = 'Delta';
            app.ThetaLabel = uilabel(app.GridLayout);
            app.ThetaLabel.Text = 'Theta';
            app.AlphaLabel = uilabel(app.GridLayout);
            app.AlphaLabel.Text = 'Alpha';
            app.BetaLabel = uilabel(app.GridLayout);
            app.BetaLabel.Text = 'Beta';
            app.Gamma1Label = uilabel(app.GridLayout);
            app.Gamma1Label.Text = 'Gamma1';
            app.Gamma2Label = uilabel(app.GridLayout);
            app.Gamma2Label.Text = 'Gamma2';
            app.spect_OtherparamsPanel = uipanel(app.SpectralTab);
            app.spect_OtherparamsPanel.Title = 'Other params';
            app.FreqresolutionLabel = uilabel(app.spect_OtherparamsPanel);
            app.FreqresolutionLabel.Text = 'Freq resolution:';
            app.spect_freq_resolEditField = uieditfield(app.spect_OtherparamsPanel, 'numeric');
            app.spect_freq_resolEditField.Limits = [0.1 1];
            app.spect_freq_resolEditField.Value = 0.5;
            app.SamplefreqLabel = uilabel(app.spect_OtherparamsPanel);
            app.SamplefreqLabel.Text = 'Sample freq:';
            app.spect_sample_freqEditField = uieditfield(app.spect_OtherparamsPanel, 'numeric');
            app.spect_sample_freqEditField.Limits = [200 600];
            app.spect_sample_freqEditField.Value = 200;
            app.FreqmaxEditFieldLabel = uilabel(app.spect_OtherparamsPanel);
            app.FreqmaxEditFieldLabel.Text = 'Freq max:';
            app.spect_FreqmaxEditField = uieditfield(app.spect_OtherparamsPanel, 'numeric');
            app.spect_FreqmaxEditField.Limits = [0 90];
            app.spect_FreqmaxEditField.Value = 90;
            app.freq_gfiltvarEditFieldLabel = uilabel(app.spect_OtherparamsPanel);
            app.freq_gfiltvarEditFieldLabel.Text = 'freq_gfiltvar:';
            app.spect_freq_gfiltvarEditField = uieditfield(app.spect_OtherparamsPanel, 'numeric');
            app.spect_freq_gfiltvarEditField.Limits = [0.1 3];
            app.spect_freq_gfiltvarEditField.Value = 0.5;
            app.win_orderLabel = uilabel(app.spect_OtherparamsPanel);
            app.win_orderLabel.Text = 'win_order:';
            app.spect_win_orderSlider = uislider(app.spect_OtherparamsPanel);
            app.spect_win_orderSlider.Limits = [1 3];
            app.spect_win_orderSlider.MajorTicks = [1 2 3];
            app.spect_win_orderSlider.MinorTicks = [1 2 3];
            app.spect_win_orderSlider.Value = 1;
            app.RunButton = uibutton(app.BCVARETAToolboxv10UIFigure, 'push');
            app.RunButton.Text = 'Run';
            app.CancelButton = uibutton(app.BCVARETAToolboxv10UIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.BCVARETAToolboxv10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BC_VARETA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BCVARETAToolboxv10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BCVARETAToolboxv10UIFigure)
        end
    end
end