classdef ExploreDS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExploreDatasetsUIFigure  matlab.ui.Figure
        Toolbar                  matlab.ui.container.Toolbar
        PushTool                 matlab.ui.container.toolbar.PushTool
        Panel_2                  matlab.ui.container.Panel
        GeneralInformationPanel  matlab.ui.container.Panel
        DSDescriptionTextArea    matlab.ui.control.TextArea
        DescriptionLabel         matlab.ui.control.Label
        DSNameLabel              matlab.ui.control.Label
        DatasetsPanel            matlab.ui.container.Panel
        DatasetsTree             matlab.ui.container.Tree
        Node                     matlab.ui.container.TreeNode
        Node2_5                  matlab.ui.container.TreeNode
        Node3_5                  matlab.ui.container.TreeNode
        Node4_5                  matlab.ui.container.TreeNode
        Node_2                   matlab.ui.container.TreeNode
        Node2_6                  matlab.ui.container.TreeNode
        Node3_6                  matlab.ui.container.TreeNode
        Node4_6                  matlab.ui.container.TreeNode
        Panel                    matlab.ui.container.Panel
        CloseButton              matlab.ui.control.Button
        ViewerPanel              matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        ActionsPanel             matlab.ui.container.Panel
        ActionsTree              matlab.ui.container.Tree
        NodeAction1              matlab.ui.container.TreeNode
        Node2_3                  matlab.ui.container.TreeNode
        Node3_3                  matlab.ui.container.TreeNode
        Node4_3                  matlab.ui.container.TreeNode
        NodeAction2              matlab.ui.container.TreeNode
        Node2_4                  matlab.ui.container.TreeNode
        Node3_4                  matlab.ui.container.TreeNode
        Node4_4                  matlab.ui.container.TreeNode
        SubjectInformationPanel  matlab.ui.container.Panel
    end


    properties (Access = public)
        Datasets; % Description
        BCVdir;
        Datasets_file;
    end

    properties (Access = private)
        currentDS; % Description
        currentPt;
    end

    methods (Access = private)

        function ParticipantSensorLevel(app,menu)
            selectedNodes = app.DatasetsTree.SelectedNodes;
            disp(selectedNodes.Text);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp, Datasets)
            app.Datasets_file = fullfile(callerApp.BCVdir,"Datasets.json");
            AllDatasets = jsondecode(fileread(app.Datasets_file));
            delete(app.DatasetsTree.Children);
            app.Datasets = Datasets;
            for i=1:length(app.Datasets)
                app.currentDS = app.Datasets(i);
                child = uitreenode(app.DatasetsTree,'Text',app.currentDS.Name);
                child.Icon = 'guide/images/box-solid.png';
                child.NodeData = app.currentDS.UUID;
                indexDS = find(contains({AllDatasets.UUID},app.currentDS.UUID),1);
                participants = AllDatasets(indexDS).Participants;
                for j=1:length(participants)
                    participant = participants(j);
                    subChild = uitreenode(child,'Text',participant.SubID);
                    subChild.Icon = 'guide/images/participant.png';
                    if(isequal(participant.Status,"Completed"))
                        mStyle = uistyle("FontColor","blue");
                    else
                        mStyle = uistyle("FontColor","red");
                    end
                    addStyle(app.DatasetsTree,mStyle,"node",subChild);
                    contexMenu = uicontextmenu(app.ExploreDatasetsUIFigure);
                    if(isequal(participant.Status,"Completed"))
                        PartInfo = load(fullfile(app.currentDS.Path,participant.SubID,participant.FileInfo));
                        processes = PartInfo.Processes;
                        for k=1:length(processes)
                            process = processes(k);
                            if(process.completed)
                                menu = uimenu(contexMenu,'Text',process.name, "MenuSelectedFcn",@ParticipantSensorLevel);
                            end
                        end
                        subChild.ContextMenu = contexMenu;
                    end
                end
            end
            expand(app.DatasetsTree);
            delete(app.ActionsTree.Children);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Selection changed function: DatasetsTree
        function DatasetsTreeSelectionChanged(app, event)
            selectedNodes = app.DatasetsTree.SelectedNodes;
            if(isequal('matlab.ui.container.Tree',class(selectedNodes.Parent)))
                datasetNode = selectedNodes;
            else
                datasetNode = selectedNodes.Parent;
            end
            app.currentDS = app.Datasets(find(contains({app.Datasets.UUID},datasetNode.NodeData),1));
            app.DSNameLabel.Text = strcat("Name: ", app.currentDS.Name);
            app.DSDescriptionTextArea.Value = app.currentDS.Description;
            participant = app.currentDS.Participants(find( ...
                contains( ...
                {app.currentDS.Participants.SubID},selectedNodes.Text),1));
            delete(app.ActionsTree.Children);
            if(isfile(fullfile(app.currentDS.Path,participant.SubID,participant.FileInfo)))
                app.currentPt = load(fullfile(app.currentDS.Path,participant.SubID,participant.FileInfo));

                %% Common and general actions
                child = uitreenode(app.ActionsTree,'Text','Commons');
                child.Icon = 'guide/images/common.png';
                child = uitreenode(app.ActionsTree,'Text','General');
                child.Icon = 'guide/images/generals.png';

                %% Sensor level actions
                child = uitreenode(app.ActionsTree,'Text','Sensor level');
                child.Icon = 'guide/images/sensor.png';
                for i=1:length(app.currentPt.sensor_level)
                    sensor = app.currentPt.sensor_level(i);
                    subChild = uitreenode(child,'Text',sensor.Freq);
                    subChild.Icon = 'guide/images/frequency.png';
                    subChild.NodeData = sensor;
                end
                %% Activation level actions
                if(isfield(app.currentPt,'activation_level') && app.currentPt.Processes(2).completed)
                    activChild = uitreenode(app.ActionsTree,'Text','Activation level');
                    activChild.Icon = 'guide/images/activation.png';
                    for i=1:length(app.currentPt.activation_level)
                        activation = app.currentPt.activation_level(i);
                        if(isequal(activation.Method,lower('sssblpp')) && ~exist('sssblChild','var'))
                            sssblChild = uitreenode(activChild,'Text','ssSBL++');
                            sssblChild.Icon = 'guide/images/methods.png';
                        end
                        if(isequal(activation.Method,lower('eloreta')) && ~exist('eloretaChild','var'))
                            eloretaChild = uitreenode(activChild,'Text','eLORETA');
                            eloretaChild.Icon = 'guide/images/methods.png';
                        end
                        if(isequal(activation.Method,lower('lcmv')) && ~exist('lcmvChild','var'))
                            lcmvChild = uitreenode(activChild,'Text','LCMV');
                            lcmvChild.Icon = 'guide/images/methods.png';
                        end
                    end
                    for i=1:length(app.currentPt.activation_level)
                        activation = app.currentPt.activation_level(i);
                        if(isequal(activation.Method,lower('sssblpp')))
                            subChild = uitreenode(sssblChild,'Text',activation.Freq);
                            subChild.Icon = 'guide/images/frequency.png';
                            subChild.NodeData = activation;
                        end
                        if(isequal(activation.Method,lower('eloreta')))
                            subChild = uitreenode(eloretaChild,'Text',activation.Freq);
                            subChild.Icon = 'guide/images/frequency.png';
                            subChild.NodeData = activation;
                        end
                        if(isequal(activation.Method,lower('lcmv')))
                            subChild = uitreenode(lcmvChild,'Text',activation.Freq);
                            subChild.Icon = 'guide/images/frequency.png';
                            subChild.NodeData = activation;
                        end
                    end
                end
                %% Connectivity actions
                if(isfield(app.currentPt,'connectivity_level') && app.currentPt.Processes(3).completed)
                    connChild = uitreenode(app.ActionsTree,'Text','Connectivity level');
                    connChild.Icon = 'guide/images/connectivity.png';
                    for i=1:length(app.currentPt.connectivitty_level)
                        connectivity = app.currentPt.connectivitty_level(i);
                        if(isequal(connectivity.Method,lower('higgs')) && ~exist('sssblChild','var'))
                            higgsChild = uitreenode(activChild,'Text','ssSBL++');
                            higgsChild.Icon = 'guide/images/methods.png';
                        end
                        if(isequal(connectivity.Method,lower('hglasso')) && ~exist('eloretaChild','var'))
                            hglassoChild = uitreenode(activChild,'Text','eLORETA');
                            hglassoChild.Icon = 'guide/images/methods.png';
                        end                        
                    end
                    for i=1:length(app.currentPt.connectivitty_level)
                        connectivity = app.currentPt.connectivitty_level(i);
                        if(isequal(connectivity.Method,lower('higgs')))
                            subChild = uitreenode(higgsChild,'Text',activation.Freq);
                            subChild.Icon = 'guide/images/frequency.png';
                            subChild.NodeData = connectivity;
                        end
                        if(isequal(activation.Method,lower('hglasso')))
                            subChild = uitreenode(hglassoChild,'Text',activation.Freq);
                            subChild.Icon = 'guide/images/frequency.png';
                            subChild.NodeData = connectivity;
                        end                        
                    end
                end
                expand(app.ActionsTree);

                %% Shows cross spectrum
                Fm      = app.currentDS.sensor_params.max_freq.value;
                deltaf  = app.currentDS.sensor_params.freq_resol.value; 
                F       = 0:deltaf:Fm;                
                load(fullfile(app.currentDS.Path,app.currentPt.SubID,'Generals/Funtional/Data_spectrum.mat'));                               
                PSD_log = 10*log10(abs(PSD)); 
                % figure_band = figure('Color','w','Name','Cross-spectrum','NumberTitle','off');                
                % hold on;
                app.UIAxes.XLabel.String = 'Freq. (Hz)';
                app.UIAxes.XColor = 'K';
                app.UIAxes.YLabel.String = 'PSD (dB)';
                app.UIAxes.YColor = 'K';
                app.UIAxes.Title.String = 'Power Spectral Density';                
                plot(app.UIAxes,F,PSD_log);           


            end

        end

        % Selection changed function: ActionsTree
        function ActionsTreeSelectionChanged(app, event)
            selectedNodes = app.ActionsTree.SelectedNodes;
            nodeData = selectedNodes.NodeData;
            data = load(fullfile(app.currentDS.Path,app.currentPt.SubID,nodeData.Ref_path,nodeData.Name));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ExploreDatasetsUIFigure = uifigure('Visible', 'off');
            app.ExploreDatasetsUIFigure.Name = 'Explore Datasets';
            app.Toolbar = uitoolbar(app.ExploreDatasetsUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.SubjectInformationPanel = uipanel(app.ExploreDatasetsUIFigure);
            app.SubjectInformationPanel.Title = 'Subject Information';
            app.ActionsPanel = uipanel(app.ExploreDatasetsUIFigure);
            app.ActionsPanel.Title = 'Actions';
            app.ActionsTree = uitree(app.ActionsPanel);
            app.ActionsTree.SelectionChangedFcn = createCallbackFcn(app, @ActionsTreeSelectionChanged, true);
            app.NodeAction1 = uitreenode(app.ActionsTree);
            app.NodeAction1.Text = 'Node';
            app.Node2_3 = uitreenode(app.NodeAction1);
            app.Node2_3.Icon = fullfile(pathToMLAPP, 'images', 'participant.png');
            app.Node2_3.Text = 'Node2';
            app.Node3_3 = uitreenode(app.NodeAction1);
            app.Node3_3.Text = 'Node3';
            app.Node4_3 = uitreenode(app.NodeAction1);
            app.Node4_3.Text = 'Node4';
            app.NodeAction2 = uitreenode(app.ActionsTree);
            app.NodeAction2.Text = 'Node';
            app.Node2_4 = uitreenode(app.NodeAction2);
            app.Node2_4.Text = 'Node2';
            app.Node3_4 = uitreenode(app.NodeAction2);
            app.Node3_4.Text = 'Node3';
            app.Node4_4 = uitreenode(app.NodeAction2);
            app.Node4_4.Text = 'Node4';
            app.ViewerPanel = uipanel(app.ExploreDatasetsUIFigure);
            app.ViewerPanel.Title = 'Viewer';
            app.UIAxes = uiaxes(app.ViewerPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.ExploreDatasetsUIFigure);
            app.CloseButton = uibutton(app.Panel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Panel_2 = uipanel(app.ExploreDatasetsUIFigure);
            app.DatasetsPanel = uipanel(app.Panel_2);
            app.DatasetsPanel.Title = 'Datasets';
            app.DatasetsTree = uitree(app.DatasetsPanel);
            app.DatasetsTree.SelectionChangedFcn = createCallbackFcn(app, @DatasetsTreeSelectionChanged, true);
            app.Node = uitreenode(app.DatasetsTree);
            app.Node.Text = 'Node';
            app.Node2_5 = uitreenode(app.Node);
            app.Node2_5.Icon = fullfile(pathToMLAPP, 'images', 'participant.png');
            app.Node2_5.Text = 'Node2';
            app.Node3_5 = uitreenode(app.Node);
            app.Node3_5.Text = 'Node3';
            app.Node4_5 = uitreenode(app.Node);
            app.Node4_5.Text = 'Node4';
            app.Node_2 = uitreenode(app.DatasetsTree);
            app.Node_2.Text = 'Node';
            app.Node2_6 = uitreenode(app.Node_2);
            app.Node2_6.Text = 'Node2';
            app.Node3_6 = uitreenode(app.Node_2);
            app.Node3_6.Text = 'Node3';
            app.Node4_6 = uitreenode(app.Node_2);
            app.Node4_6.Text = 'Node4';
            app.GeneralInformationPanel = uipanel(app.Panel_2);
            app.GeneralInformationPanel.Title = 'General Information';
            app.DSNameLabel = uilabel(app.GeneralInformationPanel);
            app.DSNameLabel.Text = 'Name:';
            app.DescriptionLabel = uilabel(app.GeneralInformationPanel);
            app.DescriptionLabel.Text = 'Description:';
            app.DSDescriptionTextArea = uitextarea(app.GeneralInformationPanel);
            app.DSDescriptionTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.ExploreDatasetsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExploreDS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExploreDatasetsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExploreDatasetsUIFigure)
        end
    end
end